classdef holo_vid_labeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        App_uifigure                 matlab.ui.Figure
        suggestbboxesButton          matlab.ui.control.Button
        showminmaxnormframeCheckBox  matlab.ui.control.CheckBox
        cutoff_frame_spinner         matlab.ui.control.Spinner
        cutoffframeSpinnerLabel      matlab.ui.control.Label
        videoplayerPanel             matlab.ui.container.Panel
        framerateEditField           matlab.ui.control.NumericEditField
        framerateEditFieldLabel      matlab.ui.control.Label
        stop_button_img              matlab.ui.control.Image
        stop_button                  matlab.ui.control.Button
        play_button_img              matlab.ui.control.Image
        play_button                  matlab.ui.control.Button
        selectframeSpinner           matlab.ui.control.Spinner
        selectframeSpinnerLabel      matlab.ui.control.Label
        newlabel_button              matlab.ui.control.StateButton
        arrow_right                  matlab.ui.control.Button
        arrow_left                   matlab.ui.control.Button
        hologramvideosListBox        matlab.ui.control.ListBox
        hologramvideosListBoxLabel   matlab.ui.control.Label
        selectvideofolderButton      matlab.ui.control.Button
        ax_holofr                    matlab.ui.control.UIAxes
    end

    properties (Access=private)
        app_path
        file_folders_count
        vid_folders_struct
        curr_vid_folder
        current_vid_struct
        current_fr_I
        current_princ_frs
        current_fr_show_minmaxnorm_I
        current_fr_cutoff
        current_fr_bboxes
        current_fr_bboxes_scaled_to_original_sz
        current_fr_bbox_labels
        current_vid_files_path
        curr_fr_struct
        curr_princ_labels_map
        curr_deriv_labels_map
        just_past_fr
        fr_no_selected
        fr_heigh
        fr_width
        bbox_scale_heigth_ax_to_img_sz
        bbox_scale_width_ax_to_img_sz
        bbox_scale_heigth_img_to_ax_sz
        bbox_scale_width_img_to_ax_sz
        txt_x
        txt_y
        fr_cutoff
        stop_now
        pause_btwn_fr
    end

    methods (Access = private)
        function update_last_fr_no(app)
            app.just_past_fr = fr;
        end
%         function apply_labels_to_all_frames(app)
%             app.current_princ_frs = cell2mat(app.curr_princ_labels_map.keys);
%             app.current_princ_frs = sort(app.current_princ_frs);
%             app.current_fr_cutoff = app.cutoff_frame_spinner.Value;
%             all_frs_to_be_labeled = 1:app.current_fr_cutoff;
%             all_bboxes = [];
%             for fr=app.current_princ_frs %clears principal frames to add labels only to secondary
%                 idx = find(all_frs_to_be_labeled == fr,1);
%                 all_frs_to_be_labeled(idx) = [];
%                 all_bboxes(end+1) = app.curr_princ_labels_map(fr);
%             end
%         end


        function load_stored_labels_into_current_fr_bboxes(app)
            if app.fr_has_at_least_one_bbox()
                fr = app.fr_no_selected(app.hologramvideosListBox.Value);
                k = cell2mat(app.curr_princ_labels_map.keys);
                idx = find(k == fr,1);
                if ~isempty(idx)
                    app.current_fr_bboxes = app.curr_princ_labels_map(fr);
                    for i=1:length(app.current_fr_bboxes)
                        app.current_fr_bboxes(i).Parent = app.ax_holofr;
                    end
                end
            end
        end
        function store_labels_n_clear_current_fr_bboxes(app)
            if app.fr_has_at_least_one_bbox()
                fr = app.fr_no_selected(app.hologramvideosListBox.Value);
                app.curr_princ_labels_map(fr) = app.current_fr_bboxes(:).copy();
                clear app.current_fr_bboxes;
                app.current_fr_bboxes = [];
            end
        end
        function update_video_list_box(app)
            try
                vid_file_dir = dir(app.current_vid_files_path);
                app.file_folders_count = app.file_folders_count + 1;
                app.hologramvideosListBox.Items = {vid_file_dir(contains({vid_file_dir(:).name},'.avi')).name};
                app.fr_no_selected = containers.Map();
                app.hologramvideosListBoxValueChanged(true);
                dummy_vid_fold_struct.files_path = app.current_vid_files_path;
                dummy_vid_fold_struct.file_names = app.hologramvideosListBox.Items;

                if isempty(app.vid_folders_struct)
                    app.vid_folders_struct = dummy_vid_fold_struct;
                else
                    app.vid_folders_struct(app.file_folders_count) = dummy_vid_fold_struct;
                end
            catch ME

                app.hologramvideosListBox.Items = {'dummy_video_1'};
                errordlg(ME.message,ME.identifier);
            end
        end
        function ret = fr_has_at_least_one_bbox(app)
            ret = ~isempty(app.current_fr_bboxes);
        end

        function ret = fr_has_NO_bboxes(app)
            ret = isempty(app.current_fr_bboxes);
        end

        function ret = video_has_been_loaded(app)
            ret = ~isempty(app.current_vid_struct);
        end
        function calc_bbox_scaling(app)
            app.bbox_scale_heigth_ax_to_img_sz = app.fr_heigh/app.ax_holofr.Position(3);
            app.bbox_scale_width_ax_to_img_sz = app.fr_width/app.ax_holofr.Position(4);
            app.bbox_scale_heigth_img_to_ax_sz = app.ax_holofr.Position(4)/app.fr_width;
            app.bbox_scale_width_img_to_ax_sz = app.ax_holofr.Position(3)/app.fr_heigh;
        end
        function del_bbox(app)
            idx = app.get_selected_bbox_idx();
            app.current_fr_bbox_labels(idx) = [];
            app.current_fr_bboxes(idx).delete();
            app.current_fr_bboxes(idx) = [];
        end

        function add_bbox_to_current_rect_array(app, bbox)
            if app.fr_has_NO_bboxes()
                app.current_fr_bboxes = bbox;
            else
                app.current_fr_bboxes(end+1) = bbox;
            end
            app.current_fr_bbox_labels{end+1} = 'conv';
            %             app.current_fr_bboxes(end).Selected = false;
            app.unselect_current_bboxes();
        end

        function idx = get_selected_bbox_idx(app)
            idx = [];
            if app.fr_has_at_least_one_bbox()
                idx = [app.current_fr_bboxes(:).Selected];
            end
        end
        function unselect_current_bboxes(app)
            for i=1:length(app.current_fr_bboxes)
                app.current_fr_bboxes(i).Selected = false;
            end
        end
        function update_frame(app, fr)
            if nargin < 2
                fr = app.fr_no_selected(app.hologramvideosListBox.Value);
            end
            
            if app.current_fr_show_minmaxnorm_I
                app.current_fr_I = app.current_vid_struct(fr).I_min_max_255;
            else
                app.current_fr_I = app.current_vid_struct(fr).I_orig;
            end

            imshow(app.current_fr_I, 'Parent',app.ax_holofr);
            app.selectframeSpinner.Value = fr;
            app.update_text_over_fr();
            app.load_stored_labels_into_current_fr_bboxes();
        end

        function update_text_over_fr(app)
            fr_no = app.fr_no_selected(app.hologramvideosListBox.Value);
            txt_over_pic = {app.hologramvideosListBox.Value, ['frame ' num2str(fr_no)]};
            text(app.ax_holofr, app.txt_x, app.txt_y, txt_over_pic,"Color",[1 1 1],"FontSize",40,"FontWeight","bold");
        end
        function init_label_holder(app)
            app.label_holder(1).vid_files_path = '';
            app.label_holder(1).label_struct = app.init_label_holder();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.just_past_fr = [];
            app.app_path = matlab.desktop.editor.getActiveFilename;
            app.app_path = regexprep(app.app_path,'\\(\w+_+\w*)+\.m','');
            cd(app.app_path);
            add_all_folders();
            app.current_fr_show_minmaxnorm_I = false;
            app.ax_holofr.Visible = 'on';
            app.current_fr_bboxes = [];
            app.current_fr_bbox_labels = {};
            app.ax_holofr.Colormap = gray(256);
            axis(app.ax_holofr, 'image');
            app.framerateEditField.Value = 5;
            app.file_folders_count = 0;
            app.stop_now = false;
            app.pause_btwn_fr = 1/app.framerateEditField.Value;
            app.curr_princ_labels_map = containers.Map('KeyType','double','ValueType','any');
            app.selectframeSpinner.Step = 1;
            app.current_vid_struct = [];
            app.current_vid_files_path = 'P:\_research\AVL\Data\_videos';
            app.update_video_list_box();
            % Update the image and histograms
            %             updateimage(app, 'peppers.png');
        end

        % Callback function
        function DropDownValueChanged(app, event)
            % Update the image and histograms
            % updateimage(app, app.DropDown.Value);
        end

        % Callback function
        function LoadButtonPushed(app, event)
            %
            %             % Display uigetfile dialog
            %             filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            %             [f, p] = uigetfile(filterspec);
            %
            %             % Make sure user didn't cancel uigetfile dialog
            %             if (ischar(p))
            %                 fname = [p f];
            % %                 updateimage(app, fname);
            %             end
        end

        % Button pushed function: selectvideofolderButton
        function selectvideofolderButtonPushed(app, event)
            app.current_vid_files_path = uigetdir();
            if ~(isnumeric(app.current_vid_files_path) || isempty(app.current_vid_files_path)) %not zero nor empty
                app.update_video_list_box();
            end
        end

        % Value changed function: hologramvideosListBox
        function hologramvideosListBoxValueChanged(app, event)
            %store remaning labels from previous video
            app.store_labels_n_clear_current_fr_bboxes();

            app.current_vid_struct = load_vid([app.current_vid_files_path '\' app.hologramvideosListBox.Value]);
            app.selectframeSpinner.Limits(2) = length(app.current_vid_struct);
            app.cutoff_frame_spinner.Limits = app.selectframeSpinner.Limits;
            app.cutoff_frame_spinner.Value = app.selectframeSpinner.Limits(2);

            try %if key is not present
                fr = app.fr_no_selected(app.hologramvideosListBox.Value);
            catch ME
                app.fr_no_selected(app.hologramvideosListBox.Value) = 1;
                fr = 1;
            end


            [app.fr_heigh, app.fr_width]  = size(app.current_vid_struct(fr).I_min_max_255);

            app.txt_x = round(app.fr_heigh/8);
            app.txt_y = round(app.fr_width/8);
            app.calc_bbox_scaling();
            app.update_frame();
        end

        % Button pushed function: arrow_left
        function arrow_leftButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.fr_no_selected(app.hologramvideosListBox.Value) = app.fr_no_selected(app.hologramvideosListBox.Value) - 1;
                if app.fr_no_selected(app.hologramvideosListBox.Value) <= 0
                    app.fr_no_selected(app.hologramvideosListBox.Value) = app.cutoff_frame_spinner.Value;
                end

                app.update_frame();
            end
        end

        % Button pushed function: arrow_right
        function arrow_rightButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.fr_no_selected(app.hologramvideosListBox.Value) = app.fr_no_selected(app.hologramvideosListBox.Value) + 1;
                if app.fr_no_selected(app.hologramvideosListBox.Value) > app.cutoff_frame_spinner.Value
                    app.fr_no_selected(app.hologramvideosListBox.Value) = 1;
                end
                app.update_frame();
            end
        end

        % Key press function: App_uifigure
        function App_uifigureKeyPress(app, event)
            if isa(event,'matlab.ui.eventdata.KeyData')
                key = event.Key;
                if strcmpi(key, 'delete')
                    app.del_bbox();
                elseif strcmpi(key, 'n')
                    app.newlabel_buttonValueChanged(true);
                else
                    app.store_labels_n_clear_current_fr_bboxes();
                    if strcmpi(key,'rightarrow')
                        app.arrow_rightButtonPushed(true)
                    elseif strcmpi(key, 'leftarrow')
                        app.arrow_leftButtonPushed(true)
                    end
                end
            end
        end

        % Button pushed function: play_button
        function play_buttonButtonPushed(app, event)
            if app.video_has_been_loaded()
                while ~app.stop_now
                    app.arrow_rightButtonPushed(true);
                    pause(app.pause_btwn_fr);
                end
                app.stop_now = false;
            end
        end

        % Value changed function: framerateEditField
        function framerateEditFieldValueChanged(app, event)
            value = app.framerateEditField.Value;
            if value <= 0
                app.framerateEditField.Value = 1;
            elseif value >= length(app.current_vid_struct)
                app.framerateEditField.Value = 10;
            end
            app.pause_btwn_fr = 1/app.framerateEditField.Value;
        end

        % Button pushed function: stop_button
        function stop_buttonButtonPushed(app, event)
            app.stop_now = true;
        end

        % Image clicked function: play_button_img
        function play_button_imgImageClicked(app, event)
            app.play_buttonButtonPushed(true);
        end

        % Image clicked function: stop_button_img
        function stop_button_imgImageClicked(app, event)
            app.stop_buttonButtonPushed(true);
        end

        % Value changed function: newlabel_button
        function newlabel_buttonValueChanged(app, event)
            if app.video_has_been_loaded()
                bbox = drawrectangle(app.ax_holofr);
                app.add_bbox_to_current_rect_array(bbox)
            end
            app.newlabel_button.Value = false;
        end

        % Button down function: ax_holofr
        function ax_holofrButtonDown(app, event)
            app.App_uifigureKeyPress(true);
        end

        % Value changed function: selectframeSpinner
        function selectframeSpinnerValueChanged(app, event)
            fr = app.selectframeSpinner.Value;
            app.store_labels_n_clear_current_fr_bboxes()
            app.update_frame(fr);
        end

        % Value changed function: cutoff_frame_spinner
        function cutoff_frame_spinnerValueChanged(app, event)
            app.selectframeSpinner.Value = app.cutoff_frame_spinner.Value;
            app.selectframeSpinnerValueChanged(true);
            %             app.update_frame(app.cutoff_frame_spinner.Value);
            %             app.selectframeSpinner.Value = app.cutoff_frame_spinner.Value;

        end

        % Value changed function: showminmaxnormframeCheckBox
        function showminmaxnormframeCheckBoxValueChanged(app, event)
            app.current_fr_show_minmaxnorm_I = app.showminmaxnormframeCheckBox.Value;
            app.update_frame();
        end

        % Button pushed function: suggestbboxesButton
        function suggestbboxesButtonPushed(app, event)
            if app.video_has_been_loaded()
                [bboxes, scores] = suggest_bboxes(app.current_fr_I);
                for i=1:length(scores)
                    bbox = drawrectangle(app.ax_holofr, 'Position', bboxes(i,:), 'StripeColor','r');
                    app.add_bbox_to_current_rect_array(bbox);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.App_uifigure = uifigure('Visible', 'off');
            app.App_uifigure.Name = 'Image Histograms';
            app.App_uifigure.KeyPressFcn = createCallbackFcn(app, @App_uifigureKeyPress, true);
            app.ax_holofr = uiaxes(app.App_uifigure);
            app.ax_holofr.XTick = [];
            app.ax_holofr.XTickLabel = {'[ ]'};
            app.ax_holofr.YTick = [];
            app.ax_holofr.ButtonDownFcn = createCallbackFcn(app, @ax_holofrButtonDown, true);
            app.selectvideofolderButton = uibutton(app.App_uifigure, 'push');
            app.selectvideofolderButton.ButtonPushedFcn = createCallbackFcn(app, @selectvideofolderButtonPushed, true);
            app.selectvideofolderButton.Text = 'select video folder';
            app.hologramvideosListBoxLabel = uilabel(app.App_uifigure);
            app.hologramvideosListBoxLabel.Text = 'hologram videos';
            app.hologramvideosListBox = uilistbox(app.App_uifigure);
            app.hologramvideosListBox.Items = {'', ''};
            app.hologramvideosListBox.ValueChangedFcn = createCallbackFcn(app, @hologramvideosListBoxValueChanged, true);
            app.hologramvideosListBox.Value = '';
            app.arrow_left = uibutton(app.App_uifigure, 'push');
            app.arrow_left.ButtonPushedFcn = createCallbackFcn(app, @arrow_leftButtonPushed, true);
            app.arrow_left.Text = '<';
            app.arrow_right = uibutton(app.App_uifigure, 'push');
            app.arrow_right.ButtonPushedFcn = createCallbackFcn(app, @arrow_rightButtonPushed, true);
            app.arrow_right.Text = '>';
            app.newlabel_button = uibutton(app.App_uifigure, 'state');
            app.newlabel_button.ValueChangedFcn = createCallbackFcn(app, @newlabel_buttonValueChanged, true);
            app.newlabel_button.Text = {'new label'; '(press n)'};
            app.selectframeSpinnerLabel = uilabel(app.App_uifigure);
            app.selectframeSpinnerLabel.Text = 'select frame';
            app.selectframeSpinner = uispinner(app.App_uifigure);
            app.selectframeSpinner.Limits = [1 Inf];
            app.selectframeSpinner.ValueChangedFcn = createCallbackFcn(app, @selectframeSpinnerValueChanged, true);
            app.selectframeSpinner.Value = 1;
            app.videoplayerPanel = uipanel(app.App_uifigure);
            app.videoplayerPanel.Title = 'video player';
            app.play_button = uibutton(app.videoplayerPanel, 'push');
            app.play_button.ButtonPushedFcn = createCallbackFcn(app, @play_buttonButtonPushed, true);
            app.play_button.Text = '';
            app.play_button_img = uiimage(app.videoplayerPanel);
            app.play_button_img.ImageClickedFcn = createCallbackFcn(app, @play_button_imgImageClicked, true);
            app.play_button_img.ImageSource = 'play button.png';
            app.stop_button = uibutton(app.videoplayerPanel, 'push');
            app.stop_button.ButtonPushedFcn = createCallbackFcn(app, @stop_buttonButtonPushed, true);
            app.stop_button.Text = '';
            app.stop_button_img = uiimage(app.videoplayerPanel);
            app.stop_button_img.ImageClickedFcn = createCallbackFcn(app, @stop_button_imgImageClicked, true);
            app.stop_button_img.ImageSource = 'stop button.png';
            app.framerateEditFieldLabel = uilabel(app.videoplayerPanel);
            app.framerateEditFieldLabel.Text = 'framerate';
            app.framerateEditField = uieditfield(app.videoplayerPanel, 'numeric');
            app.framerateEditField.ValueChangedFcn = createCallbackFcn(app, @framerateEditFieldValueChanged, true);
            app.cutoffframeSpinnerLabel = uilabel(app.App_uifigure);
            app.cutoffframeSpinnerLabel.Text = 'cutoff frame';
            app.cutoff_frame_spinner = uispinner(app.App_uifigure);
            app.cutoff_frame_spinner.Limits = [1 Inf];
            app.cutoff_frame_spinner.ValueChangedFcn = createCallbackFcn(app, @cutoff_frame_spinnerValueChanged, true);
            app.cutoff_frame_spinner.Value = 1;
            app.showminmaxnormframeCheckBox = uicheckbox(app.App_uifigure);
            app.showminmaxnormframeCheckBox.ValueChangedFcn = createCallbackFcn(app, @showminmaxnormframeCheckBoxValueChanged, true);
            app.showminmaxnormframeCheckBox.Text = 'show min-max norm frame';
            app.suggestbboxesButton = uibutton(app.App_uifigure, 'push');
            app.suggestbboxesButton.ButtonPushedFcn = createCallbackFcn(app, @suggestbboxesButtonPushed, true);
            app.suggestbboxesButton.Text = 'suggest bboxes';
            % Show the figure after all components are created
            app.App_uifigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = holo_vid_labeler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.App_uifigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.App_uifigure)
        end
    end
end