classdef holo_vid_labeler_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        App_uifigure                   matlab.ui.Figure
        arrow_right_2                  matlab.ui.control.Button
        VideoandFrameInfoLabel         matlab.ui.control.Label
        suggestionresultTextArea       matlab.ui.control.TextArea
        suggestionresultTextAreaLabel  matlab.ui.control.Label
        consolidatebboxesButton        matlab.ui.control.Button
        SuggestBBoxButton              matlab.ui.control.Button
        showminmaxnormframeCheckBox    matlab.ui.control.CheckBox
        cutoff_frame_spinner           matlab.ui.control.Spinner
        cutoffframeSpinnerLabel        matlab.ui.control.Label
        videoplayerPanel               matlab.ui.container.Panel
        framerateEditField             matlab.ui.control.NumericEditField
        framerateEditFieldLabel        matlab.ui.control.Label
        stop_button                    matlab.ui.control.Button
        play_button                    matlab.ui.control.Button
        selectframeSpinner             matlab.ui.control.Spinner
        selectframeSpinnerLabel        matlab.ui.control.Label
        newlabel_button                matlab.ui.control.StateButton
        arrow_right                    matlab.ui.control.Button
        arrow_left                     matlab.ui.control.Button
        hologramvideosListBox          matlab.ui.control.ListBox
        hologramvideosListBoxLabel     matlab.ui.control.Label
        selectvideofolderButton        matlab.ui.control.Button
        ax_holofr                      matlab.ui.control.UIAxes
    end

    properties (Access=private)
        app_path
        file_folders_count
        vid_folders_struct
        curr_vid_obj
        holo_vids_holder_obj
        current_vid_files_path
        just_past_fr
        stop_now
        pause_btwn_fr
        stop_button_img     matlab.ui.control.Image
        play_button_img     matlab.ui.control.Image
        ctrl_pressed
    end

    methods (Access = private)
        % Image clicked function: play_button_img
        %         function play_button_imgImageClicked(app, event)
        %             app.play_buttonButtonPushed(true);
        %         end
        %
        %         % Image clicked function: stop_button_img
        %         function stop_button_imgImageClicked(app, event)
        %             app.stop_buttonButtonPushed(true);
        %         end

        function update_last_fr_no(app)
            app.just_past_fr = fr;
        end
        function disp_play_stop_icons(app)
            % Create play_button_img
            app.play_button_img = uiimage(app.videoplayerPanel);
            app.play_button_img.ImageClickedFcn = createCallbackFcn(app, @play_button_imgImageClicked, true);
            app.play_button_img.Position = [29 16 22 26];
            app.play_button_img.ImageSource = 'play button.png';


            % Create stop_button_img
            app.stop_button_img = uiimage(app.videoplayerPanel);
            app.stop_button_img.ImageClickedFcn = createCallbackFcn(app, @stop_button_imgImageClicked, true);
            app.stop_button_img.Position = [130 16 21 26];
            app.stop_button_img.ImageSource = 'stop button.png';

        end
        function update_video_list_box(app)
            try
                vid_file_dir = dir(app.current_vid_files_path);
                app.file_folders_count = app.file_folders_count + 1;
                app.hologramvideosListBox.Items = {vid_file_dir(contains({vid_file_dir(:).name},'.avi')).name};
                app.hologramvideosListBox.Value  = app.hologramvideosListBox.Items{1};
                app.hologramvideosListBoxValueChanged(true);
                dummy_vid_fold_struct.files_path = app.current_vid_files_path;
                dummy_vid_fold_struct.file_names = app.hologramvideosListBox.Items;

                if isempty(app.vid_folders_struct)
                    app.vid_folders_struct = dummy_vid_fold_struct;
                else
                    app.vid_folders_struct(app.file_folders_count) = dummy_vid_fold_struct;
                end
            catch ME

                app.hologramvideosListBox.Items = {'dummy_video_1'};
                %                 errordlg(ME.message,ME.identifier);
            end
        end

        function ret = video_has_been_loaded(app)
            ret = ~isempty(app.curr_vid_obj) && isvalid(app.curr_vid_obj);
        end

        function init_label_holder(app)
            app.label_holder(1).vid_files_path = '';
            app.label_holder(1).label_struct = app.init_label_holder();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            % app.consolidatebboxesButton.Visible = 'off';

            app.app_path = pwd;

            if isunix()
                app.app_path = regexprep(app.app_path,'//(\w+_*\w*)+\.m','');
            else
                app.app_path = regexprep(app.app_path,'\\(\w+_*\w*)+\.m','');
            end
            add_app_folders_to_path();

            cd(app.app_path);

            app.ax_holofr.Visible = 'on';
            app.ax_holofr.Colormap = gray(256);
            axis(app.ax_holofr, 'image');
            app.framerateEditField.Value = 5;
            app.file_folders_count = 0;
            app.stop_now = false;

            app.pause_btwn_fr = 1/app.framerateEditField.Value;
            app.selectframeSpinner.Step = 1;
            app.curr_vid_obj = [];
            if exist('current_vids_path.mat','file') == 2
                load('current_vids_path.mat');
                app.current_vid_files_path = current_vid_files_path;
            else
                app.current_vid_files_path = 'Data\videos';
            end

            if isunix()
                app.current_vid_files_path = regexprep(app.current_vid_files_path,'\\','/');
            end

            vid_files = dir(app.current_vid_files_path);
            idx = find(contains({vid_files(:).name}, '.avi'),1);
            if ~isempty(idx)
                app.holo_vids_holder_obj = HoloVidsHolder(app.current_vid_files_path, app.ax_holofr);
            end
            app.update_video_list_box();
            app.disp_play_stop_icons();
            app.ctrl_pressed = false;
            % Update the image and histograms
            %             updateimage(app, 'peppers.png');
        end

        % Callback function
        function DropDownValueChanged(app, event)
            % Update the image and histograms
            % updateimage(app, app.DropDown.Value);
        end

        % Callback function
        function LoadButtonPushed(app, event)

        end

        % Button pushed function: selectvideofolderButton
        function selectvideofolderButtonPushed(app, event)
            app.current_vid_files_path = uigetdir();
            if ~(isnumeric(app.current_vid_files_path) || isempty(app.current_vid_files_path)) %not zero nor empty
                if app.video_has_been_loaded()
                    if isa(app.holo_vids_holder_obj,'HoloVidsHolder')
                        app.holo_vids_holder_obj.add_holo_vid_obj_to_map(app.curr_vid_obj);
                    end
                    %                     app.holo_vids_holder_obj.save_labels();
                end
                addpath(app.current_vid_files_path);
                if ~isa(app.holo_vids_holder_obj,'HoloVidsHolder')
                    app.holo_vids_holder_obj = HoloVidsHolder(app.current_vid_files_path, app.ax_holofr);
                end
                app.update_video_list_box();
            end
        end

        % Value changed function: hologramvideosListBox
        function hologramvideosListBoxValueChanged(app, event)
            %store remaning labels from previous video
            if app.video_has_been_loaded()
                app.holo_vids_holder_obj.add_holo_vid_obj_to_map(app.curr_vid_obj);
                %STORE VIDEO OBJ INTO MAP HERE
            end
            vid_name_no_ext = app.hologramvideosListBox.Value;
            vid_name_no_ext = vid_name_no_ext(1:end-4);

            if app.holo_vids_holder_obj.vid_is_in_map(vid_name_no_ext)
                app.curr_vid_obj = app.holo_vids_holder_obj.get_vid_obj_from_map(vid_name_no_ext);
            else
                file_path = [app.current_vid_files_path '\' app.hologramvideosListBox.Value];
                if isunix()
                    file_path = regexprep(file_path,'\\','/');
                end
                app.curr_vid_obj = HoloVid(file_path, app.ax_holofr);
            end
            app.framerateEditField.Value = app.curr_vid_obj.get_fr_rate();
            app.framerateEditFieldValueChanged(true);
            app.selectframeSpinner.Limits(2) = app.curr_vid_obj.no_of_frames;
            app.cutoff_frame_spinner.Limits = app.selectframeSpinner.Limits;
            app.cutoff_frame_spinner.Value = app.curr_vid_obj.cutoff_frame_no;
            app.showminmaxnormframeCheckBoxValueChanged(true);
            app.selectframeSpinnerValueChanged(true);
        end

        % Button pushed function: arrow_left
        function arrow_leftButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.prev_fr();
                app.selectframeSpinner.Value = app.curr_vid_obj.selected_fr_no;
                app.selectframeSpinnerValueChanged(true);
            end
        end

        % Button pushed function: arrow_right
        function arrow_rightButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.next_fr();
                app.selectframeSpinner.Value = app.curr_vid_obj.selected_fr_no;
                app.selectframeSpinnerValueChanged(true);
            end
        end

        % Key press function: App_uifigure
        function App_uifigureKeyPress(app, event)
            if isa(event,'matlab.ui.eventdata.KeyData')
                key = event.Key;
                if strcmpi(key, 'delete')
                    app.curr_vid_obj.delete_selected_bbox();
                elseif strcmpi(key, 'n')
                    app.newlabel_buttonValueChanged(true);
                else
                    if strcmpi(key,'rightarrow')
                        app.arrow_rightButtonPushed(true)
                    elseif strcmpi(key, 'leftarrow')
                        app.arrow_leftButtonPushed(true)
                    elseif strcmpi(key, 'C')
                        app.curr_vid_obj.paste_selected_bbox();
                        %                         app.ctrl_pressed = true;
                        %                     elseif strcmpi(key, 'V')
                        %                         if app.ctrl_pressed
                        %                             app.ctrl_pressed = false;
                        app.curr_vid_obj.paste_selected_bbox();
                        %                         end
                    end
                end
            else
                evname = event.EventName;
                switch(evname)
                    case{'MovingROI'}
                        app.curr_vid_obj.change_bbox_color();
                end
            end
            pause(0.2);
        end

        % Button pushed function: play_button
        function play_buttonButtonPushed(app, event)
            if app.video_has_been_loaded()
                while ~app.stop_now
                    app.arrow_rightButtonPushed(true);
                    pause(app.pause_btwn_fr);
                end
                app.stop_now = false;
            end
        end

        % Value changed function: framerateEditField
        function framerateEditFieldValueChanged(app, event)
            value = app.framerateEditField.Value;
            if value <= 0
                app.framerateEditField.Value = 1;
            elseif value >= 30
                app.framerateEditField.Value = 30;
            end
            app.pause_btwn_fr = 1/app.framerateEditField.Value;
        end

        % Button pushed function: stop_button
        function stop_buttonButtonPushed(app, event)
            app.stop_now = true;
        end

        % Callback function
        function play_button_imgImageClicked(app, event)
            app.play_buttonButtonPushed(true);
        end

        % Callback function
        function stop_button_imgImageClicked(app, event)
            app.stop_buttonButtonPushed(true);
        end

        % Value changed function: newlabel_button
        function newlabel_buttonValueChanged(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.add_bbox_to_selected_fr();
            end
            app.newlabel_button.Value = false;
        end

        % Button down function: ax_holofr
        function ax_holofrButtonDown(app, event)
            app.App_uifigureKeyPress(event);
        end

        % Value changed function: selectframeSpinner
        function selectframeSpinnerValueChanged(app, event)
            fr = app.selectframeSpinner.Value;
            if app.video_has_been_loaded()
                app.curr_vid_obj.select_fr(fr);
            end

%             app.App_uifigureKeyPress(true);
            txt_under_ax = {[' ' app.curr_vid_obj.vid_name]; [' frame ' num2str(app.curr_vid_obj.selected_fr_no)]};
            app.VideoandFrameInfoLabel.Text = txt_under_ax;
        end

        % Value changed function: cutoff_frame_spinner
        function cutoff_frame_spinnerValueChanged(app, event)
            fr = app.cutoff_frame_spinner.Value;
            if app.video_has_been_loaded()
                app.curr_vid_obj.set_cutoff_fr(fr);
            end
            %             if app.curr_vid_obj.selected_fr_no > fr
            app.selectframeSpinner.Value = fr;
            app.selectframeSpinnerValueChanged(true);
            %             end
        end

        % Value changed function: showminmaxnormframeCheckBox
        function showminmaxnormframeCheckBoxValueChanged(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.set_show_I_minmax(app.showminmaxnormframeCheckBox.Value);
            end
        end

        % Callback function: SuggestBBoxButton, 
        % suggestionresultTextArea
        function suggestbboxesButtonPushed(app, event)
            if app.video_has_been_loaded()
                found_in_minmax_norm = false;
                [bboxes, scores] = suggest_bboxes(app.curr_vid_obj.get_I_orig());
                if isempty(scores)
                    [bboxes, scores] = suggest_bboxes(app.curr_vid_obj.get_I_min_max_norm());
                    if ~isempty(found_in_minmax_norm)
                        found_in_minmax_norm = true;
                    end
                end
                txt_minmax_norm = '';
                if found_in_minmax_norm
                    txt_minmax_norm = ' using min-max normalized frame image.';
                end
                txt_display_bboxes = {' suggested for ', ['frame ' num2str(app.curr_vid_obj.selected_fr_no)], txt_minmax_norm};
                if isempty(scores)
                    app.suggestionresultTextArea.Value = [{'No bounding boxes'} txt_display_bboxes];
                    app.suggestionresultTextArea.FontColor = 'r';
                    app.suggestionresultTextArea.FontWeight =  'normal';
                else
                    app.suggestionresultTextArea.Value = [{[num2str(length(scores)) ' bounding boxes']} txt_display_bboxes];
                    app.suggestionresultTextArea.FontColor = 'k';
                    app.suggestionresultTextArea.FontWeight = 'bold';
                    for i=1:length(scores)
                        bbox = drawrectangle(app.ax_holofr, 'Position', bboxes(i,:), 'StripeColor','r');
                        app.curr_vid_obj.add_bbox_to_selected_fr(bbox);
                    end
                end
            end
        end

        % Button pushed function: consolidatebboxesButton
        function consolidatebboxesButtonPushed(app, event)
            app.curr_vid_obj.combine_bboxes_from_all_fr();
        end

        % Close request function: App_uifigure
        function App_uifigureCloseRequest(app, event)
            if app.video_has_been_loaded()
                app.holo_vids_holder_obj.add_holo_vid_obj_to_map(app.curr_vid_obj);
                app.holo_vids_holder_obj.save_labels();
                current_vid_files_path = app.current_vid_files_path;
                if isunix()
                    save('AppData/current_vids_path.mat','current_vid_files_path');
                else
                    save('AppData\current_vids_path.mat','current_vid_files_path');
                end
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.App_uifigure = uifigure('Visible', 'off');
            app.App_uifigure.Name = 'Holo Video Labeler';
            app.App_uifigure.CloseRequestFcn = createCallbackFcn(app, @App_uifigureCloseRequest, true);
            app.App_uifigure.KeyPressFcn = createCallbackFcn(app, @App_uifigureKeyPress, true);
            app.ax_holofr = uiaxes(app.App_uifigure);
            app.ax_holofr.XTick = [];
            app.ax_holofr.XTickLabel = {'[ ]'};
            app.ax_holofr.YTick = [];
            app.ax_holofr.ButtonDownFcn = createCallbackFcn(app, @ax_holofrButtonDown, true);
            app.selectvideofolderButton = uibutton(app.App_uifigure, 'push');
            app.selectvideofolderButton.ButtonPushedFcn = createCallbackFcn(app, @selectvideofolderButtonPushed, true);
            app.selectvideofolderButton.Text = 'select video folder';
            app.hologramvideosListBoxLabel = uilabel(app.App_uifigure);
            app.hologramvideosListBoxLabel.Text = 'hologram videos';
            app.hologramvideosListBox = uilistbox(app.App_uifigure);
            app.hologramvideosListBox.Items = {'', ''};
            app.hologramvideosListBox.ValueChangedFcn = createCallbackFcn(app, @hologramvideosListBoxValueChanged, true);
            app.hologramvideosListBox.Value = '';
            app.arrow_left = uibutton(app.App_uifigure, 'push');
            app.arrow_left.ButtonPushedFcn = createCallbackFcn(app, @arrow_leftButtonPushed, true);
            app.arrow_left.Text = '<';
            app.arrow_right = uibutton(app.App_uifigure, 'push');
            app.arrow_right.ButtonPushedFcn = createCallbackFcn(app, @arrow_rightButtonPushed, true);
            app.arrow_right.Text = '>';
            app.newlabel_button = uibutton(app.App_uifigure, 'state');
            app.newlabel_button.ValueChangedFcn = createCallbackFcn(app, @newlabel_buttonValueChanged, true);
            app.newlabel_button.Text = {'New BBox'; '(press N on '; 'the keyboard)'};
            app.selectframeSpinnerLabel = uilabel(app.App_uifigure);
            app.selectframeSpinnerLabel.Text = 'select frame';
            app.selectframeSpinner = uispinner(app.App_uifigure);
            app.selectframeSpinner.Limits = [1 Inf];
            app.selectframeSpinner.ValueChangedFcn = createCallbackFcn(app, @selectframeSpinnerValueChanged, true);
            app.selectframeSpinner.Value = 1;
            app.videoplayerPanel = uipanel(app.App_uifigure);
            app.videoplayerPanel.Title = 'video player';
            app.play_button = uibutton(app.videoplayerPanel, 'push');
            app.play_button.ButtonPushedFcn = createCallbackFcn(app, @play_buttonButtonPushed, true);
            app.play_button.Text = '';
            app.stop_button = uibutton(app.videoplayerPanel, 'push');
            app.stop_button.ButtonPushedFcn = createCallbackFcn(app, @stop_buttonButtonPushed, true);
            app.stop_button.Text = '';
            app.framerateEditFieldLabel = uilabel(app.videoplayerPanel);
            app.framerateEditFieldLabel.Text = 'framerate';
            app.framerateEditField = uieditfield(app.videoplayerPanel, 'numeric');
            app.framerateEditField.ValueChangedFcn = createCallbackFcn(app, @framerateEditFieldValueChanged, true);
            app.cutoffframeSpinnerLabel = uilabel(app.App_uifigure);
            app.cutoffframeSpinnerLabel.Text = 'cutoff frame';
            app.cutoff_frame_spinner = uispinner(app.App_uifigure);
            app.cutoff_frame_spinner.Limits = [1 Inf];
            app.cutoff_frame_spinner.ValueChangedFcn = createCallbackFcn(app, @cutoff_frame_spinnerValueChanged, true);
            app.cutoff_frame_spinner.Value = 1;
            app.showminmaxnormframeCheckBox = uicheckbox(app.App_uifigure);
            app.showminmaxnormframeCheckBox.ValueChangedFcn = createCallbackFcn(app, @showminmaxnormframeCheckBoxValueChanged, true);
            app.showminmaxnormframeCheckBox.Text = 'show min-max norm frame';
            app.SuggestBBoxButton = uibutton(app.App_uifigure, 'push');
            app.SuggestBBoxButton.ButtonPushedFcn = createCallbackFcn(app, @suggestbboxesButtonPushed, true);
            app.SuggestBBoxButton.Text = 'Suggest BBox';
            app.consolidatebboxesButton = uibutton(app.App_uifigure, 'push');
            app.consolidatebboxesButton.ButtonPushedFcn = createCallbackFcn(app, @consolidatebboxesButtonPushed, true);
            app.consolidatebboxesButton.Enable = 'off';
            app.consolidatebboxesButton.Visible = 'off';
            app.consolidatebboxesButton.Text = 'consolidate bboxes';
            app.suggestionresultTextAreaLabel = uilabel(app.App_uifigure);
            app.suggestionresultTextAreaLabel.Text = 'suggestion result';
            app.suggestionresultTextArea = uitextarea(app.App_uifigure);
            app.suggestionresultTextArea.ValueChangedFcn = createCallbackFcn(app, @suggestbboxesButtonPushed, true);
            app.suggestionresultTextArea.Value = {'summary of results for *suggest box* are shown here.'};
            app.VideoandFrameInfoLabel = uilabel(app.App_uifigure);
            app.VideoandFrameInfoLabel.Text = {' Video and '; ' Frame Info'};
            app.arrow_right_2 = uibutton(app.App_uifigure, 'push');
            app.arrow_right_2.Text = 'o>';
            % Show the figure after all components are created
            app.App_uifigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = holo_vid_labeler_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.App_uifigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.App_uifigure)
        end
    end
end