classdef ui_holo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        App_uifigure                    matlab.ui.Figure
        deletebboxesinallvideosButton   matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        videoplayerPanel                matlab.ui.container.Panel
        framerateEditField              matlab.ui.control.NumericEditField
        framerateEditFieldLabel         matlab.ui.control.Label
        stop_button                     matlab.ui.control.Button
        play_button                     matlab.ui.control.Button
        arrow_right                     matlab.ui.control.Button
        arrow_left                      matlab.ui.control.Button
        frameSpinner                    matlab.ui.control.Spinner
        frameSpinnerLabel               matlab.ui.control.Label
        hologramvideosListBox           matlab.ui.control.ListBox
        hologramvideosListBoxLabel      matlab.ui.control.Label
        rem_invalid_bboxes              matlab.ui.control.Button
        doublecheckedCheckBox           matlab.ui.control.CheckBox
        stdizeoverallframesCheckBox     matlab.ui.control.CheckBox
        arrow_left_add_bboxes           matlab.ui.control.Button
        COPYALLBBOXESPanel              matlab.ui.container.Panel
        arrow_right_add_bboxes          matlab.ui.control.Button
        minmaxnormframeCheckBox         matlab.ui.control.CheckBox
        selectvideosButton              matlab.ui.control.Button
        BoundingBoxesPanel              matlab.ui.container.Panel
        applyclasstoselectedbboxButton  matlab.ui.control.Button
        removclassButton                matlab.ui.control.Button
        addclassButton                  matlab.ui.control.Button
        classDropDown                   matlab.ui.control.DropDown
        classDropDownLabel              matlab.ui.control.Label
        newlabel_button                 matlab.ui.control.StateButton
        deletebboxesfromthisframeonButton  matlab.ui.control.Button
        FasterRCNNNeuralNetworkPanel    matlab.ui.container.Panel
        py_modelonlyLabel               matlab.ui.control.Label
        nmsthresholdEditField           matlab.ui.control.NumericEditField
        nmsthresholdLabel               matlab.ui.control.Label
        scoreEditField                  matlab.ui.control.NumericEditField
        scoreEditFieldLabel             matlab.ui.control.Label
        OPTIONSBELOWAREONLYRECOMMENDEDIFYOUHAVEANNVIDIAGPULabel  matlab.ui.control.Label
        detectinallvideosVERYslowButton  matlab.ui.control.Button
        detectfeatureinallframesButton  matlab.ui.control.Button
        statusTextArea                  matlab.ui.control.TextArea
        statusTextAreaLabel             matlab.ui.control.Label
        detectfeatureinframeButton      matlab.ui.control.Button
        selectdetectorListBox           matlab.ui.control.ListBox
        selectdetectorListBoxLabel      matlab.ui.control.Label
        deleteallbboxesinvideoButton    matlab.ui.control.Button
        deleteallbboxesinframeButton    matlab.ui.control.Button
        NovideofoundLabel               matlab.ui.control.Label
        ax_holofr                       matlab.ui.control.UIAxes
    end

    properties (Access=private)
        detect_list_obj
        detector_found
        vid_file_names
        app_path
        vid_folders_struct
        curr_vid_obj
        holo_vids_holder_obj
        holo_vids_holder_array
        current_vid_files_path
        just_past_fr
        timer_vid_player
        
        pause_btwn_fr
        ctrl_pressed
        copy_bboxes_to_next_fr
        copy_bboxes_to_prev_fr
        done_videos
        class_id_color
        detection_colors
        detect_score_thresh
        stop_detection
        frames_where_detection_happened
        class_id_selected
        change_class_of_selected_bbox
    end
    
    methods (Access = private)
        function class_id = get_selected_class_id(app)
            class_selected = app.classDropDown.Value;
            [idx_start, idx_end] = regexpi(class_selected, '\d+(?= - )');
            class_id = str2double(class_selected(idx_start:idx_end));
        end
        function M = make_class_id_color_matrix(app)
            s = app.class_id_color.keys;
            M = zeros(length(s),3);
            for i=1:length(s)
                M(i,:) = app.class_id_color(s{i});
            end
        end
        function init_class_ids(app)
            app.class_id_color = containers.Map('KeyType', 'double','ValueType','any');
            if exist('app_data','dir')
                class_id_file_path = 'app_data\class_idx.txt';
                class_color_info_path = 'app_data\class_colors.txt';
                if isunix()
                    class_id_file_path = regexprep(class_id_file_path,'\\+','/');
                    class_color_info_path= regexprep(class_color_info_path,'\\+','/');
                end
                if exist(class_id_file_path, 'file')
                    app.classDropDown.Items = readcell(class_id_file_path);
                end
                
                if exist(class_color_info_path, 'file')
                    M = readmatrix(class_color_info_path);
                    
                    [r,~] = size(M);
                    for i=0:r-1
                        app.class_id_color(i) = M(i+1,:);
                    end
                end
            end
            if isempty(app.class_id_color)
                L = length(app.classDropDown.Items);
                app.class_id_color(0) = [0 0.447 0.741]; %normal blue box for feature,
                app.class_id_color(1) = [0.9 0.5 0.1]; %orange for other
                if L > 2
                    for i=2:L
                        app.class_id_color(i) = rand(1,3);
                    end
                end
            end
        end
        function refresh_detector_list(app)
            app.detect_list_obj = DetectorList();
            if ~app.detect_list_obj.detector_found
                app.selectdetectorListBox.Items = {};
            else
                app.selectdetectorListBox.Items = app.detect_list_obj.detectors_in_dir;
                app.selectdetectorListBox.Value = app.selectdetectorListBox.Items{1};
                app.selectdetectorListBoxValueChanged(true);
                app.detector_found = true;
            end
        end
        function init_vid_player_timer(app)
            app.timer_vid_player = timer();
            app.timer_vid_player.TimerFcn = @(x,y)run_video(app);
            app.timer_vid_player.ExecutionMode = 'fixedRate';
            stop(app.timer_vid_player);
            app.timer_vid_player.BusyMode = 'queue';
            app.timer_vid_player.Period = app.pause_btwn_fr;
            app.timer_vid_player.Name = 'vid_player';
        end
        function load_done_videos_list(app)
            if exist('done_videos.txt',"file") == 2
                dummy = readcell('done_videos.txt');
                if ~isempty(dummy)
                    vid_names = dummy(:,1);
                    done_logical = dummy(:,2);
                    for i=1:length(vid_names)
                        f = vid_names{i};
                        app.done_videos(f) = done_logical{i};
                    end
                end
            else
                writecell({},'done_videos');
            end
        end
        function store_done_vid_names(app)
            vid_names = app.done_videos.keys;
            done_vid_logical = cell(length(vid_names),2);
            for i=1:length(done_vid_logical)
                done_vid_logical{i,1} = vid_names{i};
                done_vid_logical{i,2} = app.done_videos(vid_names{i});
            end
            writecell(done_vid_logical,'done_videos.txt','Delimiter',' ');
        end
        function store_holo_vid_holder_obj_into_array(app)
            if ~app.holo_vids_holder_obj.is_empty()
                if isempty(app.holo_vids_holder_array)
                    app.holo_vids_holder_array = app.holo_vids_holder_obj.copy;
                else
                    app.holo_vids_holder_array(end+1) = app.holo_vids_holder_obj.copy;
                end
            end
        end
        function convert_fr_rate_to_ms(app)
            app.pause_btwn_fr = double(uint16(app.pause_btwn_fr*1000))/1000;
        end
        function run_video(app)
            app.arrow_rightButtonPushed(true);
        end
        function get_app_path(app)
            app_path_dummy = mfilename('fullpath');

            slash = '\\';
            if isunix()
                slash = '/';
            end

            app.app_path = regexprep(app_path_dummy, [slash 'ui_holo.*'],'');

            cd(app.app_path);
        end
        function init_path_is_set = init_vid_files_path(app)
            
            vid_files_NOT_found = app.get_vid_names_in_current_vid_files_path();

            if vid_files_NOT_found
                if exist('_videos','dir') == 7 %tries first in default directory
                    app.current_vid_files_path = '_videos';
                    vid_files_NOT_found = app.get_vid_names_in_current_vid_files_path();
                end
            end

            if vid_files_NOT_found %tries then in stored path from the app's last run
                if exist('curr_vids_path.mat','file') == 2
                    l_struct = load('curr_vids_path.mat');
                    f_name = fieldnames(l_struct);
                    app.current_vid_files_path = l_struct.(f_name{1});
                    vid_files_NOT_found = app.get_vid_names_in_current_vid_files_path();
                end
            end

            init_path_is_set = ~vid_files_NOT_found;
        end
        function vid_files_NOT_found = get_vid_names_in_current_vid_files_path(app)
            dir_data = dir(app.current_vid_files_path);
            f_names = {dir_data(:).name};
            idx = find(contains(f_names, '.avi'));
            vid_files_NOT_found = isempty(idx);

            if vid_files_NOT_found
                app.vid_file_names = {};
                app.current_vid_files_path = '';
            else
                app.vid_file_names = f_names(idx);
            end
        end
        function update_last_fr_no(app)
            app.just_past_fr = fr;
        end

        function update_video_list_box(app)
            % vid_file_dir = dir(app.current_vid_files_path);
            % vid_names = {vid_file_dir(:).name};
            % idx = find(contains(vid_names,'.avi'));

            if isempty(app.vid_file_names)
                app.hologramvideosListBox.Items = {};
                app.current_vid_files_path = '';
                warndlg('No videos found.');
            else
                if ischar(app.vid_file_names)
                    app.vid_file_names = {app.vid_file_names};
                end
%                 idx = randperm(length(app.vid_file_names));
%                 app.vid_file_names = app.vid_file_names(idx);
                app.hologramvideosListBox.Items = app.vid_file_names;
                app.hologramvideosListBox.Value  = app.hologramvideosListBox.Items{1};
                app.hologramvideosListBoxValueChanged(true);
            end
            app.update_folders_seen_struct();
        end

        function update_folders_seen_struct(app)
            dummy_vid_folders_struct.files_path = app.current_vid_files_path;
            dummy_vid_folders_struct.file_names = app.hologramvideosListBox.Items;

            if isempty(app.vid_folders_struct)
                app.vid_folders_struct = dummy_vid_folders_struct;
            else
                app.vid_folders_struct(end+1) = dummy_vid_folders_struct;
            end
        end

        function ret = video_has_been_loaded(app)
            ret = ~isempty(app.curr_vid_obj) && isvalid(app.curr_vid_obj) && isa(app.curr_vid_obj,'HoloVid');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, vid_path)
            % Configure image axes
            if nargin < 2 
                vid_path = '';
            end
            app.stop_detection = false;
            app.init_class_ids();
            app.detector_found = false;
            app.get_app_path();
            add_app_folders_to_path();
            app.ax_holofr.Visible = 'on';
            app.ax_holofr.Colormap = gray(256);
            axis(app.ax_holofr, 'image');
            app.framerateEditField.Value = 5;
            app.pause_btwn_fr = 1/app.framerateEditField.Value;
            app.frameSpinner.Step = 1;
            app.init_vid_player_timer();

            app.curr_vid_obj = [];
            app.copy_bboxes_to_next_fr = false;
            app.copy_bboxes_to_prev_fr = false;
            app.vid_file_names = {};
            app.ctrl_pressed = false;
            
            app.current_vid_files_path = vid_path;
            init_path_is_set = app.init_vid_files_path();
            app.done_videos = containers.Map('KeyType','double','ValueType','any');
            app.load_done_videos_list();
            app.refresh_detector_list();
            
            if init_path_is_set
                app.holo_vids_holder_obj = HoloVidsHolder(app.app_path, app.ax_holofr, app.current_vid_files_path);
                app.update_video_list_box();
            else
                app.holo_vids_holder_obj = HoloVidsHolder(app.app_path, app.ax_holofr);
            end
            app.holo_vids_holder_array = [];
        end

        % Button pushed function: selectvideosButton
        function selectvideosButtonPushed(app, event)
            [app.vid_file_names, app.current_vid_files_path] = uigetfile('*.avi','Select One or More Files','MultiSelect','on');
            if isnumeric(app.current_vid_files_path) || isempty(app.current_vid_files_path) %not zero nor empty
                app.current_vid_files_path = '';
                app.vid_file_names = {};
            else
                if app.holo_vids_holder_obj.vid_files_path_is_NOT_set()
                    app.holo_vids_holder_obj.set_vid_files_path(app.current_vid_files_path);
                else
                    if app.video_has_been_loaded() %chaging folders, storing objects related to last seen videos so it can be loaded next if needed
                        cd(app.app_path);
%                         app.curr_vid_obj.rem_invalid_bboxes_from_all_fr();
                        app.holo_vids_holder_obj.add_holo_vid_obj_to_map(app.curr_vid_obj);
                        app.holo_vids_holder_obj.save_labels();
                    end
                    
                    app.store_holo_vid_holder_obj_into_array();
                    
                    app.holo_vids_holder_obj = HoloVidsHolder(app.app_path, app.ax_holofr, app.current_vid_files_path); %creates new obj

                end

                addpath(app.current_vid_files_path);
                app.update_video_list_box();
            end
        end

        % Value changed function: hologramvideosListBox
        function hologramvideosListBoxValueChanged(app, event)

            if app.video_has_been_loaded() %store remaning labels from previous video
                app.holo_vids_holder_obj.add_holo_vid_obj_to_map(app.curr_vid_obj);
                %STORE VIDEO OBJ INTO MAP HERE
            end
            vid_name_no_ext = app.hologramvideosListBox.Value;
            vid_name_no_ext = vid_name_no_ext(1:end-4);

            if app.holo_vids_holder_obj.vid_is_in_map(vid_name_no_ext)
                app.curr_vid_obj = app.holo_vids_holder_obj.get_vid_obj_from_map(vid_name_no_ext);
            else
                file_path = [app.current_vid_files_path '\' app.hologramvideosListBox.Value];
                if isunix()
                    file_path = regexprep(file_path,'\\','/');
                end
                app.curr_vid_obj = HoloVid(file_path, app.ax_holofr, app.minmaxnormframeCheckBox.Value);
            end
            if app.done_videos.isKey(app.curr_vid_obj.vid_name)
                app.doublecheckedCheckBox.Value = app.done_videos(app.curr_vid_obj.vid_name);
            else
                app.doublecheckedCheckBox.Value = false;
            end
%             app.ax_holofr
            app.framerateEditField.Value = app.curr_vid_obj.get_fr_rate();
            app.framerateEditFieldValueChanged(true);
            app.frameSpinner.Limits(2) = app.curr_vid_obj.no_of_frames;
            app.frameSpinner.Value = app.curr_vid_obj.selected_fr_no;
            app.stdizeoverallframesCheckBoxValueChanged(true);
            app.frameSpinnerValueChanged(true);
            
        end

        % Button pushed function: arrow_left
        function arrow_leftButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.prev_fr(app.copy_bboxes_to_prev_fr);
                app.copy_bboxes_to_prev_fr = false;
                app.frameSpinner.Value = app.curr_vid_obj.selected_fr_no;
                app.frameSpinnerValueChanged(true);
            end
        end

        % Button pushed function: arrow_right
        function arrow_rightButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.next_fr(app.copy_bboxes_to_next_fr);
                app.copy_bboxes_to_next_fr = false;

                app.frameSpinner.Value = app.curr_vid_obj.selected_fr_no;
                app.frameSpinnerValueChanged(true);
            end
        end

        % Key press function: App_uifigure
        function App_uifigureKeyPress(app, event)
            if isa(event,'matlab.ui.eventdata.KeyData')
                key = event.Key;
                ctrl_related_key_has_NOT_been_pressed = true;
                if app.ctrl_pressed
                    if strcmpi(key, 'Z')
                        app.curr_vid_obj.reload_last_deleted_bbox();
                        ctrl_related_key_has_NOT_been_pressed = false;
                    end
                end

                if ctrl_related_key_has_NOT_been_pressed
                    app.ctrl_pressed = false;
                    if strcmpi(key, 'control')
                        app.ctrl_pressed = true;
                    elseif strcmpi(key, 'delete')
                        app.curr_vid_obj.delete_selected_bbox();
                    elseif strcmpi(key, 'n')
                        app.newlabel_buttonValueChanged(true);
                    elseif strcmpi(key,'rightarrow')
                        app.arrow_rightButtonPushed(true)
                    elseif strcmpi(key, 'leftarrow')
                        app.arrow_leftButtonPushed(true)
                    elseif strcmpi(key, 'C')
                        app.curr_vid_obj.paste_selected_bbox();
                    end
                end
            end
            pause(0.1);
        end

        % Button pushed function: play_button
        function play_buttonButtonPushed(app, event)
            if app.video_has_been_loaded()
                if strcmpi(app.timer_vid_player.Running,'off')
                    start(app.timer_vid_player);
                end
            end
        end

        % Value changed function: framerateEditField
        function framerateEditFieldValueChanged(app, event)
            value = app.framerateEditField.Value;
            vid_was_playing = false;
            if value <= 0
                app.framerateEditField.Value = 1;
            elseif value >= 20
                app.framerateEditField.Value = 20;
            end

            app.pause_btwn_fr = 1/app.framerateEditField.Value;
            app.convert_fr_rate_to_ms();

            if strcmpi(app.timer_vid_player.Running,'on')
                vid_was_playing = true;
                stop(app.timer_vid_player);
            end
            app.timer_vid_player.Period = app.pause_btwn_fr;

            if vid_was_playing
                app.play_buttonButtonPushed(true);
            end
        end

        % Button pushed function: stop_button
        function stop_buttonButtonPushed(app, event)
            if strcmpi(app.timer_vid_player.Running,'on')
                stop(app.timer_vid_player);
            end
        end

        % Value changed function: newlabel_button
        function newlabel_buttonValueChanged(app, event)
            if app.video_has_been_loaded()
                class_id = app.get_selected_class_id();
                app.curr_vid_obj.add_bbox_to_selected_fr([], num2str(class_id), app.class_id_color(class_id));
            end
            app.newlabel_button.Value = false;
        end

        % Button down function: ax_holofr
        function ax_holofrButtonDown(app, event)
            app.App_uifigureKeyPress(event);
        end

        % Value changed function: frameSpinner
        function frameSpinnerValueChanged(app, event)
            if app.video_has_been_loaded()
                fr = app.frameSpinner.Value;
                app.curr_vid_obj.select_fr(fr);
                txt_under_ax = {[' ' app.curr_vid_obj.vid_name]; [' frame ' num2str(app.curr_vid_obj.selected_fr_no)]};
                app.NovideofoundLabel.Text = txt_under_ax;
            end
        end

        % Value changed function: minmaxnormframeCheckBox
        function minmaxnormframeCheckBoxValueChanged(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.set_show_I_minmax(app.minmaxnormframeCheckBox.Value);
            end
        end

        % Callback function: detectfeatureinframeButton, 
        % statusTextArea
        function suggestbboxesButtonPushed(app, event)
            if app.video_has_been_loaded()
                if ~app.detector_found
                    app.refresh_detector_list();
                end
                if ~app.stop_detection
                    if app.detector_found
                        found_in_minmax_norm = false;
                        [bboxes, scores] = app.detect_list_obj.suggest_bboxes(app.curr_vid_obj.get_I_orig(), app.scoreEditField.Value);
                        if isempty(scores)
                            [bboxes, scores] = app.detect_list_obj.suggest_bboxes(app.curr_vid_obj.get_I_min_max_norm(), app.scoreEditField.Value);
                            if ~isempty(found_in_minmax_norm)
                                found_in_minmax_norm = true;
                            end
                        end
                        txt_minmax_norm = '';
                        if found_in_minmax_norm
                            txt_minmax_norm = ' using min-max normalized frame image.';
                        end
                        txt_display_bboxes = {' suggested for ', ['frame ' num2str(app.curr_vid_obj.selected_fr_no)], txt_minmax_norm};
                        if isempty(scores)
                            app.statusTextArea.Value = [{'No bounding boxes'} txt_display_bboxes];
                            app.statusTextArea.FontColor = 'r';
                            app.statusTextArea.FontWeight =  'normal';
                        else
                            app.statusTextArea.Value = [{[num2str(length(scores)) ' bounding boxes']} txt_display_bboxes];
                            app.statusTextArea.FontColor = 'k';
                            app.statusTextArea.FontWeight = 'bold';
    %                         NN_name = regexprep(app.selectdetectorListBox.Value,'detector|_|.mat','');
                            for i=1:length(scores)
                                sc = regexprep(num2str(double(uint8(scores(i)*100))/100), '.*\.','.');
                                
                                bbox = drawrectangle(app.ax_holofr, 'Position', bboxes(i,:), 'StripeColor','r','Label',...
                                                        sc);
                                app.curr_vid_obj.add_bbox_to_selected_fr(bbox);
                                
                            end
                            j = app.curr_vid_obj.selected_fr_no;
                            app.frames_where_detection_happened(j) = 1;
                        end
                    end
                end
            end
        end

        % Close request function: App_uifigure
        function App_uifigureCloseRequest(app, event)
            if app.video_has_been_loaded()
                app.detect_list_obj.kill_py_model();
                cd(app.app_path);
                
                app.holo_vids_holder_obj.add_holo_vid_obj_to_map(app.curr_vid_obj); 
                app.store_holo_vid_holder_obj_into_array();
                                
                for i=1:length(app.holo_vids_holder_array)
                    hol_vid_hold_obj = app.holo_vids_holder_array(i);
                    hol_vid_hold_obj.save_labels();
                end
                
                if exist('app_data','dir') ~= 7
                    mkdir('app_data');
                end
                
                class_info_dir = 'app_data\class_idx.txt';
                curr_vid_dir_store_path = 'app_data\curr_vids_path.mat';
                class_color_info_path = 'app_data\class_colors.txt';
                if isunix()
                    class_info_dir = regexprep(class_info_dir,'\\+','/');
                    curr_vid_dir_store_path = regexprep(curr_vid_dir_store_path,'\\+','/');
                    class_color_info_path = regexprep(class_color_info_path,'\\+','/');
                end
                curr_vid_files_path = app.current_vid_files_path;
                save(curr_vid_dir_store_path,'curr_vid_files_path');
                writecell(app.classDropDown.Items, class_info_dir);
                
                writematrix(app.make_class_id_color_matrix(), class_color_info_path);
            end
            savepath pathdef.m;
            app.timer_vid_player = [];
            delete(app)
        end

        % Button pushed function: arrow_right_add_bboxes
        function arrow_right_add_bboxesButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.copy_bboxes_to_next_fr = true;
                app.arrow_rightButtonPushed(true);
            end
        end

        % Value changing function: frameSpinner
        function frameSpinnerValueChanging(app, event)
            changingValue = event.Value;

        end

        % Button pushed function: deleteallbboxesinframeButton
        function deleteallbboxesinframeButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.delete_all_bboxes_in_selected_fr();
            end
        end

        % Button pushed function: deleteallbboxesinvideoButton
        function deleteallbboxesinvideoButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.delete_all_bboxes_in_video();
            end
        end

        % Button pushed function: arrow_left_add_bboxes
        function arrow_left_add_bboxesButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.copy_bboxes_to_prev_fr = true;
                app.arrow_leftButtonPushed(true);
            end
        end

        % Value changed function: stdizeoverallframesCheckBox
        function stdizeoverallframesCheckBoxValueChanged(app, event)
            vid_norm = app.stdizeoverallframesCheckBox.Value;
            if vid_norm
                app.curr_vid_obj.norm_over_vid();
                app.curr_vid_obj.set_show_I_minmax(3);
            else
                app.curr_vid_obj.set_show_I_minmax(app.minmaxnormframeCheckBox.Value);
            end
        end

        % Button down function: App_uifigure, BoundingBoxesPanel, 
        % FasterRCNNNeuralNetworkPanel, Panel
        function App_uifigureButtonDown(app, event)
            %             app.App_uifigure.KeyPressFcn
            app.App_uifigureKeyPress(event);
        end

        % Value changed function: doublecheckedCheckBox
        function doublecheckedCheckBoxValueChanged(app, event)
            if app.video_has_been_loaded()
                app.done_videos(app.curr_vid_obj.vid_name) = app.doublecheckedCheckBox.Value;
            end
        end

        % Callback function
        function deleteallbboxesfromthisframeonButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.delete_all_bboxes_in_and_after_sel_fr();
            end
        end

        % Value changed function: selectdetectorListBox
        function selectdetectorListBoxValueChanged(app, event)
            detector_name = app.selectdetectorListBox.Value;
            app.statusTextArea.Value = ['Loading ' detector_name];
            app.statusTextArea.FontColor = 'm';
            app.statusTextArea.FontWeight =  'normal';
            pause(0.001);
            app.detect_list_obj.load_detector(detector_name);
            app.statusTextArea.Value = ['Completed loading ' detector_name];
            app.statusTextArea.FontColor = 'b';
        end

        % Button pushed function: detectfeatureinallframesButton
        function detectfeatureinallframesButtonPushed(app, event)
            if app.video_has_been_loaded()
                detector_name = app.selectdetectorListBox.Value;
                last_frame_sel_no = app.frameSpinner.Value;
                app.frames_where_detection_happened = zeros(app.curr_vid_obj.no_of_frames,1);
                no_detection_thresh = floor(app.curr_vid_obj.no_of_frames/2);
                for i=1:app.curr_vid_obj.no_of_frames
                    app.statusTextArea.Value = ['Running ' detector_name ' on frame ' num2str(i)];
                    app.statusTextArea.FontColor = 'm';
                    app.statusTextArea.FontWeight =  'normal';
                    pause(0.001);
                    if app.stop_detection
                        break;
                    end
                    app.frameSpinner.Value = i;
                    app.curr_vid_obj.select_fr(i);
                    app.suggestbboxesButtonPushed(true);
                    
                    if i > no_detection_thresh && sum(app.frames_where_detection_happened(i-10:i)) == 0
                        break;
                    end
                end
                app.frameSpinner.Value = last_frame_sel_no;
                app.frameSpinnerValueChanged();
                app.stop_detection = false;
            end
        end

        % Button pushed function: deletebboxesfromthisframeonButton
        function deletebboxesfromthisframeonButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.delete_all_bboxes_in_and_after_sel_fr();
            end
        end

        % Button pushed function: detectinallvideosVERYslowButton
        function detectinallvideosVERYslowButtonPushed(app, event)
            all_vids = app.hologramvideosListBox.Items;
            last_seen_video = app.hologramvideosListBox.Value;
            detector_name = app.selectdetectorListBox.Value;
            for i=1:length(all_vids)
                app.statusTextArea.Value = ['Running ' detector_name 'on ' all_vids{i}];
                app.statusTextArea.FontColor = 'm';
                app.statusTextArea.FontWeight =  'normal';
                app.hologramvideosListBox.Value = all_vids{i};
                pause(0.001);
                if app.stop_detection
                    break;
                end
                app.hologramvideosListBoxValueChanged();
                app.detectfeatureinallframesButtonPushed();
            end
            app.stop_detection = false;
            app.hologramvideosListBox.Value = last_seen_video;
            app.hologramvideosListBoxValueChanged();
        end

        % Button pushed function: rem_invalid_bboxes
        function rem_invalid_bboxesButtonPushed(app, event)
            all_vids = app.hologramvideosListBox.Items;
            last_seen_video = app.hologramvideosListBox.Value;
            app.statusTextArea.FontColor = 'k';
            app.statusTextArea.FontWeight =  'normal';          

            for i=1:length(all_vids)

                app.hologramvideosListBoxValueChanged(true);
            end
            app.hologramvideosListBox.Value = last_seen_video;
            app.hologramvideosListBoxValueChanged();
        end

        % Button pushed function: addclassButton
        function addclassButtonPushed(app, event)
            prompt = {'Add new bounding box class id:'};
            dlgtitle = 'New BBox class id';
            L = length(app.classDropDown.Items);
            dims = 1;
            definput = {['feature ' num2str(L)]};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            if ~isempty(answer)
                new_class_id = [num2str(L) ' - ' answer{1}];
                app.classDropDown.Items{end+1} = new_class_id;
            end
            
            app.class_id_color(L) = rand(1,3);
        end

        % Button pushed function: removclassButton
        function removclassButtonPushed(app, event)
            L = length(app.classDropDown.Items);
            rem_val = app.classDropDown.Value;
            if L > 2 && ~contains(rem_val, '0') && ~contains(rem_val, 'other')
                idx = contains(app.classDropDown.Items, rem_val);
                if ~isempty(idx)
                    app.classDropDown.Items(idx) = [];
                end
            end
        end

        % Value changed function: classDropDown
        function classDropDownValueChanged(app, event)
            app.class_id_selected = app.get_selected_class_id();
        end

        % Value changed function: scoreEditField
        function scoreEditFieldValueChanged(app, event)
            if app.video_has_been_loaded()
                app.detect_list_obj.set_obj_nms_on_py_model(app.scoreEditField.Value, app.nmsthresholdEditField.Value);
            end
        end

        % Value changed function: nmsthresholdEditField
        function nmsthresholdEditFieldValueChanged(app, event)
            if app.video_has_been_loaded()
                app.detect_list_obj.set_obj_nms_on_py_model(app.scoreEditField.Value, app.nmsthresholdEditField.Value);
            end
        end

        % Button pushed function: deletebboxesinallvideosButton
        function deletebboxesinallvideosButtonPushed(app, event)
            all_vids = app.hologramvideosListBox.Items;
            last_seen_video = app.hologramvideosListBox.Value;
            for i=1:length(all_vids)
                app.statusTextArea.Value = ['Deleting bboxes from ' all_vids{i}];
                pause(0.001);
                app.hologramvideosListBox.Value = all_vids{i};
                app.hologramvideosListBoxValueChanged(true);
                app.deleteallbboxesinvideoButtonPushed(true);
            end
            app.hologramvideosListBox.Value = last_seen_video;
            app.hologramvideosListBoxValueChanged(true);
        end

        % Callback function
        function changeclassofselectedbboxCheckBoxValueChanged(app, event)
            app.change_class_of_selected_bbox = app.changeclassofselectedbboxCheckBox.Value;            
        end

        % Button pushed function: applyclasstoselectedbboxButton
        function applyclasstoselectedbboxButtonPushed(app, event)
            if app.video_has_been_loaded()
                new_class.class_id = num2str(app.class_id_selected);
                new_class.color = app.class_id_color(app.class_id_selected);
                app.curr_vid_obj.change_class_of_selected_bbox(new_class);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.App_uifigure = uifigure('Visible', 'off');
            app.App_uifigure.Name = 'Holo Video Labeler';
            app.App_uifigure.CloseRequestFcn = createCallbackFcn(app, @App_uifigureCloseRequest, true);
            app.App_uifigure.ButtonDownFcn = createCallbackFcn(app, @App_uifigureButtonDown, true);
            app.App_uifigure.KeyPressFcn = createCallbackFcn(app, @App_uifigureKeyPress, true);
            app.ax_holofr = uiaxes(app.App_uifigure);
            app.ax_holofr.XTick = [];
            app.ax_holofr.XTickLabel = {'[ ]'};
            app.ax_holofr.YTick = [];
            app.ax_holofr.ButtonDownFcn = createCallbackFcn(app, @ax_holofrButtonDown, true);
            app.NovideofoundLabel = uilabel(app.App_uifigure);
            app.NovideofoundLabel.Text = 'No video found.';
            app.deleteallbboxesinframeButton = uibutton(app.App_uifigure, 'push');
            app.deleteallbboxesinframeButton.ButtonPushedFcn = createCallbackFcn(app, @deleteallbboxesinframeButtonPushed, true);
            app.deleteallbboxesinframeButton.Text = {'delete all bboxes '; 'in frame'};
            app.deleteallbboxesinvideoButton = uibutton(app.App_uifigure, 'push');
            app.deleteallbboxesinvideoButton.ButtonPushedFcn = createCallbackFcn(app, @deleteallbboxesinvideoButtonPushed, true);
            app.deleteallbboxesinvideoButton.Text = {'delete all '; 'bboxes in video'};
            app.FasterRCNNNeuralNetworkPanel = uipanel(app.App_uifigure);
            app.FasterRCNNNeuralNetworkPanel.Title = 'Faster RCNN Neural Network';
            app.FasterRCNNNeuralNetworkPanel.ButtonDownFcn = createCallbackFcn(app, @App_uifigureButtonDown, true);
            app.selectdetectorListBoxLabel = uilabel(app.FasterRCNNNeuralNetworkPanel);
            app.selectdetectorListBoxLabel.Text = 'select detector';
            app.selectdetectorListBox = uilistbox(app.FasterRCNNNeuralNetworkPanel);
            app.selectdetectorListBox.Items = {'', ''};
            app.selectdetectorListBox.ValueChangedFcn = createCallbackFcn(app, @selectdetectorListBoxValueChanged, true);
            app.selectdetectorListBox.Value = '';
            app.detectfeatureinframeButton = uibutton(app.FasterRCNNNeuralNetworkPanel, 'push');
            app.detectfeatureinframeButton.ButtonPushedFcn = createCallbackFcn(app, @suggestbboxesButtonPushed, true);
            app.detectfeatureinframeButton.Text = 'detect feature in frame';
            app.statusTextAreaLabel = uilabel(app.FasterRCNNNeuralNetworkPanel);
            app.statusTextAreaLabel.Text = 'status';
            app.statusTextArea = uitextarea(app.FasterRCNNNeuralNetworkPanel);
            app.statusTextArea.ValueChangedFcn = createCallbackFcn(app, @suggestbboxesButtonPushed, true);
            app.statusTextArea.Value = {'summary of results for *detect feature* are shown here.'};
            app.detectfeatureinallframesButton = uibutton(app.FasterRCNNNeuralNetworkPanel, 'push');
            app.detectfeatureinallframesButton.ButtonPushedFcn = createCallbackFcn(app, @detectfeatureinallframesButtonPushed, true);
            app.detectfeatureinallframesButton.Text = {'detect feature'; 'in all frames'};
            app.detectinallvideosVERYslowButton = uibutton(app.FasterRCNNNeuralNetworkPanel, 'push');
            app.detectinallvideosVERYslowButton.ButtonPushedFcn = createCallbackFcn(app, @detectinallvideosVERYslowButtonPushed, true);
            app.detectinallvideosVERYslowButton.Text = {'detect in all videos'; '(VERY slow)'};
            app.OPTIONSBELOWAREONLYRECOMMENDEDIFYOUHAVEANNVIDIAGPULabel = uilabel(app.FasterRCNNNeuralNetworkPanel);
            app.OPTIONSBELOWAREONLYRECOMMENDEDIFYOUHAVEANNVIDIAGPULabel.Text = 'OPTIONS BELOW ARE ONLY RECOMMENDED IF YOU HAVE AN NVIDIA GPU';
            app.scoreEditFieldLabel = uilabel(app.FasterRCNNNeuralNetworkPanel);
            app.scoreEditFieldLabel.Text = 'score';
            app.scoreEditField = uieditfield(app.FasterRCNNNeuralNetworkPanel, 'numeric');
            app.scoreEditField.Limits = [0.05 0.99];
            app.scoreEditField.ValueChangedFcn = createCallbackFcn(app, @scoreEditFieldValueChanged, true);
            app.scoreEditField.Value = 0.5;
            app.nmsthresholdLabel = uilabel(app.FasterRCNNNeuralNetworkPanel);
            app.nmsthresholdLabel.Text = 'nms threshold';
            app.nmsthresholdEditField = uieditfield(app.FasterRCNNNeuralNetworkPanel, 'numeric');
            app.nmsthresholdEditField.Limits = [0.05 0.95];
            app.nmsthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @nmsthresholdEditFieldValueChanged, true);
            app.nmsthresholdEditField.Value = 0.39;
            app.py_modelonlyLabel = uilabel(app.FasterRCNNNeuralNetworkPanel);
            app.py_modelonlyLabel.Text = 'py_model only:';
            app.deletebboxesfromthisframeonButton = uibutton(app.App_uifigure, 'push');
            app.deletebboxesfromthisframeonButton.ButtonPushedFcn = createCallbackFcn(app, @deletebboxesfromthisframeonButtonPushed, true);
            app.deletebboxesfromthisframeonButton.Text = 'delete bboxes from this frame on';
            app.BoundingBoxesPanel = uipanel(app.App_uifigure);
            app.BoundingBoxesPanel.Title = 'Bounding Boxes';
            app.BoundingBoxesPanel.ButtonDownFcn = createCallbackFcn(app, @App_uifigureButtonDown, true);
            app.newlabel_button = uibutton(app.BoundingBoxesPanel, 'state');
            app.newlabel_button.ValueChangedFcn = createCallbackFcn(app, @newlabel_buttonValueChanged, true);
            app.newlabel_button.Text = {'new BBox'; '(press N)'};
            app.classDropDownLabel = uilabel(app.BoundingBoxesPanel);
            app.classDropDownLabel.Text = 'class';
            app.classDropDown = uidropdown(app.BoundingBoxesPanel);
            app.classDropDown.Items = {'0 - feature', '1 - other'};
            app.classDropDown.ValueChangedFcn = createCallbackFcn(app, @classDropDownValueChanged, true);
            app.classDropDown.Value = '0 - feature';
            app.addclassButton = uibutton(app.BoundingBoxesPanel, 'push');
            app.addclassButton.ButtonPushedFcn = createCallbackFcn(app, @addclassButtonPushed, true);
            app.addclassButton.Text = '+';
            app.removclassButton = uibutton(app.BoundingBoxesPanel, 'push');
            app.removclassButton.ButtonPushedFcn = createCallbackFcn(app, @removclassButtonPushed, true);
            app.removclassButton.Text = '-';
            app.applyclasstoselectedbboxButton = uibutton(app.BoundingBoxesPanel, 'push');
            app.applyclasstoselectedbboxButton.ButtonPushedFcn = createCallbackFcn(app, @applyclasstoselectedbboxButtonPushed, true);
            app.applyclasstoselectedbboxButton.Text = {'apply class to '; 'selected bbox'};
            app.Panel = uipanel(app.App_uifigure);
            app.Panel.ButtonDownFcn = createCallbackFcn(app, @App_uifigureButtonDown, true);
            app.selectvideosButton = uibutton(app.Panel, 'push');
            app.selectvideosButton.ButtonPushedFcn = createCallbackFcn(app, @selectvideosButtonPushed, true);
            app.selectvideosButton.Text = 'select video(s)';
            app.minmaxnormframeCheckBox = uicheckbox(app.Panel);
            app.minmaxnormframeCheckBox.ValueChangedFcn = createCallbackFcn(app, @minmaxnormframeCheckBoxValueChanged, true);
            app.minmaxnormframeCheckBox.Text = 'min-max norm frame';
            app.COPYALLBBOXESPanel = uipanel(app.Panel);
            app.COPYALLBBOXESPanel.Title = 'COPY ALL BBOXES';
            app.arrow_right_add_bboxes = uibutton(app.COPYALLBBOXESPanel, 'push');
            app.arrow_right_add_bboxes.ButtonPushedFcn = createCallbackFcn(app, @arrow_right_add_bboxesButtonPushed, true);
            app.arrow_right_add_bboxes.Text = '>';
            app.arrow_left_add_bboxes = uibutton(app.Panel, 'push');
            app.arrow_left_add_bboxes.ButtonPushedFcn = createCallbackFcn(app, @arrow_left_add_bboxesButtonPushed, true);
            app.arrow_left_add_bboxes.Enable = 'off';
            app.arrow_left_add_bboxes.Visible = 'off';
            app.arrow_left_add_bboxes.Text = '<';
            app.stdizeoverallframesCheckBox = uicheckbox(app.Panel);
            app.stdizeoverallframesCheckBox.ValueChangedFcn = createCallbackFcn(app, @stdizeoverallframesCheckBoxValueChanged, true);
            app.stdizeoverallframesCheckBox.Enable = 'off';
            app.stdizeoverallframesCheckBox.Visible = 'off';
            app.stdizeoverallframesCheckBox.Text = 'stdize over all frames';
            app.doublecheckedCheckBox = uicheckbox(app.Panel);
            app.doublecheckedCheckBox.ValueChangedFcn = createCallbackFcn(app, @doublecheckedCheckBoxValueChanged, true);
            app.doublecheckedCheckBox.Enable = 'off';
            app.doublecheckedCheckBox.Visible = 'off';
            app.doublecheckedCheckBox.Text = 'double-checked';
            app.rem_invalid_bboxes = uibutton(app.Panel, 'push');
            app.rem_invalid_bboxes.ButtonPushedFcn = createCallbackFcn(app, @rem_invalid_bboxesButtonPushed, true);
            app.rem_invalid_bboxes.Enable = 'off';
            app.rem_invalid_bboxes.Visible = 'off';
            app.rem_invalid_bboxes.Text = {'remove invalid bboxes '; 'from all videos'};
            app.hologramvideosListBoxLabel = uilabel(app.Panel);
            app.hologramvideosListBoxLabel.Text = 'hologram videos';
            app.hologramvideosListBox = uilistbox(app.Panel);
            app.hologramvideosListBox.Items = {'', ''};
            app.hologramvideosListBox.ValueChangedFcn = createCallbackFcn(app, @hologramvideosListBoxValueChanged, true);
            app.hologramvideosListBox.Value = '';
            app.frameSpinnerLabel = uilabel(app.Panel);
            app.frameSpinnerLabel.Text = 'frame';
            app.frameSpinner = uispinner(app.Panel);
            app.frameSpinner.ValueChangingFcn = createCallbackFcn(app, @frameSpinnerValueChanging, true);
            app.frameSpinner.Limits = [1 Inf];
            app.frameSpinner.ValueChangedFcn = createCallbackFcn(app, @frameSpinnerValueChanged, true);
            app.frameSpinner.Value = 1;
            app.arrow_left = uibutton(app.Panel, 'push');
            app.arrow_left.ButtonPushedFcn = createCallbackFcn(app, @arrow_leftButtonPushed, true);
            app.arrow_left.Text = '<';
            app.arrow_right = uibutton(app.Panel, 'push');
            app.arrow_right.ButtonPushedFcn = createCallbackFcn(app, @arrow_rightButtonPushed, true);
            app.arrow_right.Text = '>';
            app.videoplayerPanel = uipanel(app.Panel);
            app.videoplayerPanel.Title = 'video player';
            app.play_button = uibutton(app.videoplayerPanel, 'push');
            app.play_button.ButtonPushedFcn = createCallbackFcn(app, @play_buttonButtonPushed, true);
            app.play_button.Text = '▶';
            app.stop_button = uibutton(app.videoplayerPanel, 'push');
            app.stop_button.ButtonPushedFcn = createCallbackFcn(app, @stop_buttonButtonPushed, true);
            app.stop_button.Text = '▇';
            app.framerateEditFieldLabel = uilabel(app.videoplayerPanel);
            app.framerateEditFieldLabel.Text = 'framerate';
            app.framerateEditField = uieditfield(app.videoplayerPanel, 'numeric');
            app.framerateEditField.ValueChangedFcn = createCallbackFcn(app, @framerateEditFieldValueChanged, true);
            app.deletebboxesinallvideosButton = uibutton(app.App_uifigure, 'push');
            app.deletebboxesinallvideosButton.ButtonPushedFcn = createCallbackFcn(app, @deletebboxesinallvideosButtonPushed, true);
            app.deletebboxesinallvideosButton.Text = 'delete bboxes in all videos';
            % Show the figure after all components are created
            app.App_uifigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui_holo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.App_uifigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.App_uifigure)
        end
    end
end